classdef BlackAndWhiteConverterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        BWImageAxes      matlab.ui.control.UIAxes
        HistogramAxes    matlab.ui.control.UIAxes
        SliderLabel      matlab.ui.control.Label
        ThresholdSlider  matlab.ui.control.Slider
        OKButton         matlab.ui.control.Button
        CancelButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        GrayScaleImage
        Threshold = 100
        Parent
        ThresholdLine
    end
    
    methods (Access = private)
        function updateBWImage(app)
            licensePlateBW = app.GrayScaleImage < app.Threshold;
            imshow(licensePlateBW, 'Parent',app.BWImageAxes)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, grayScaleImage, threshold, parentApp)
            app.Threshold = threshold;
            app.ThresholdSlider.Value = app.Threshold;
            app.GrayScaleImage = grayScaleImage;
            updateBWImage(app)
            [N,edges] = histcounts(grayScaleImage);
            bar(app.HistogramAxes, edges(1:end-1),N)
            hold(app.HistogramAxes, 'on')
            app.ThresholdLine = plot(app.HistogramAxes, [app.Threshold app.Threshold], ylim(app.HistogramAxes), 'r');
            app.Parent = parentApp;
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.ThresholdLine.XData = [value value];
            app.Threshold = value;
            updateBWImage(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setBrightnessThreshold(app.Parent, app.Threshold)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            value = event.Value;
            app.ThresholdLine.XData = [value value];
            app.Threshold = value;
            updateBWImage(app)
            drawnow limitrate
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BWImageAxes = uiaxes(app.UIFigure);
            title(app.BWImageAxes, 'Black & White Image')
            xlabel(app.BWImageAxes, '')
            ylabel(app.BWImageAxes, '')
            app.HistogramAxes = uiaxes(app.UIFigure);
            title(app.HistogramAxes, 'Brightness Histogram')
            xlabel(app.HistogramAxes, 'Brightness')
            ylabel(app.HistogramAxes, 'Pixel Count')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 100;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlackAndWhiteConverterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end