% description: This app identifies the location of license plate characters on a license plate image and identifies each character.
% Copyright © Angora Tutor, 2020. All rights reserved.
classdef LicensePlateRecognitionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LicensePlateRecognitionUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        LicenseplaterecognitionexplainedMenu  matlab.ui.container.Menu
        LicenseplaterecognitionMATLABcodeMenu  matlab.ui.container.Menu
        ContactthetutorMenu            matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        SelectlicenseplatepictureEditFieldLabel  matlab.ui.control.Label
        SelectlicenseplatepictureEditField  matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        FullImageAxes                  matlab.ui.control.UIAxes
        SelectdisplaytypeButtonGroup   matlab.ui.container.ButtonGroup
        OriginalButton                 matlab.ui.control.RadioButton
        GrayscaleButton                matlab.ui.control.RadioButton
        BlackWhiteButton               matlab.ui.control.RadioButton
        UpdateBWThresholdButton        matlab.ui.control.Button
        ROIAxes                        matlab.ui.control.UIAxes
        UpdateHorizontalThresholdButton  matlab.ui.control.Button
        Character1                     matlab.ui.control.UIAxes
        Character2                     matlab.ui.control.UIAxes
        Character3                     matlab.ui.control.UIAxes
        Character4                     matlab.ui.control.UIAxes
        Character5                     matlab.ui.control.UIAxes
        Character6                     matlab.ui.control.UIAxes
        Character7                     matlab.ui.control.UIAxes
        Character8                     matlab.ui.control.UIAxes
        LicenseNumberText              matlab.ui.control.Label
        LicensePlateNumberLabel        matlab.ui.control.Label
        UpdateVerticalThresholdButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Version string (Major.Minor)
        Version = "1.0"
        LicensePlate = zeros(400,800)
        LicensePlateGray
        LicensePlateBW
        BrightnessThreshold = 100
        LicensePlateROI = zeros(200,800)
        HorizontalThreshold = 100
        VerticalThreshold = 5
        Char1 = zeros(40,20)
        Char2 = zeros(40,20)
        Char3 = zeros(40,20)
        Char4 = zeros(40,20)
        Char5 = zeros(40,20)
        Char6 = zeros(40,20)
        Char7 = zeros(40,20)
        Char8 = zeros(40,20)
        CandidateImage
        LicenseNumber = '        '
    end
    
    methods (Access = private)
        function readImage(app)
            app.LicensePlate = imread(app.SelectlicenseplatepictureEditField.Value);
            app.LicensePlateGray = rgb2gray(app.LicensePlate);
            app.LicensePlateBW = app.LicensePlateGray < app.BrightnessThreshold;
            findROI(app)
            findChars(app)
        end
        
        function showImage(app)
            if app.OriginalButton.Value
                imshow(app.LicensePlate,'Parent',app.FullImageAxes)
            elseif app.GrayscaleButton.Value
                imshow(app.LicensePlateGray,'Parent',app.FullImageAxes)
            else
                imshow(app.LicensePlateBW,'Parent',app.FullImageAxes)
            end
            imshow(app.LicensePlateROI, 'Parent',app.ROIAxes)
            imshow(app.Char1, 'Parent', app.Character1)
            imshow(app.Char2, 'Parent', app.Character2)
            imshow(app.Char3, 'Parent', app.Character3)
            imshow(app.Char4, 'Parent', app.Character4)
            imshow(app.Char5, 'Parent', app.Character5)
            imshow(app.Char6, 'Parent', app.Character6)
            imshow(app.Char7, 'Parent', app.Character7)
            imshow(app.Char8, 'Parent', app.Character8)
        end
        
        function findROI(app)
            whiteCountPerRow = sum(app.LicensePlateBW,2);
            regions = [0; whiteCountPerRow; 0] > app.HorizontalThreshold;
            startIdx = find(diff(regions)==1);
            endIdx = find(diff(regions)==-1);
            if endIdx(end) > size(app.LicensePlateBW,1)
                endIdx(end) = size(app.LicensePlateBW,1);
            end
            if length(startIdx) > length(endIdx)
                startIdx = startIdx(1:end-1);
            end
            if length(startIdx) < length(endIdx)
                endIdx = endIdx(1:end-1);
            end
            [~,widestRegionIdx] = max(endIdx-startIdx);
            upperLimitROI = startIdx(widestRegionIdx);
            lowerLimitROI = endIdx(widestRegionIdx);
            app.LicensePlateROI = app.LicensePlateBW(upperLimitROI:lowerLimitROI,:);
        end
        
        function findChars(app)
            try
                whiteCountPerColumn = sum(app.LicensePlateROI,1);
                regions = [0 whiteCountPerColumn 0] > app.VerticalThreshold;
                startIdx = find(diff(regions)==1);
                endIdx = find(diff(regions)==-1);
                if endIdx(end) > size(app.LicensePlateBW,2)
                    endIdx(end) = size(app.LicensePlateBW,2);
                end
                if length(startIdx) > length(endIdx)
                    startIdx = startIdx(1:end-1);
                end
                if length(startIdx) < length(endIdx)
                    endIdx = endIdx(1:end-1);
                end
                regions = endIdx-startIdx;
                widthThreshold = mean(regions);
                
                count = 1;
                for p=1:length(regions)
                    if regions(p) > (widthThreshold-std(regions)) && (count <= 8)
                        % Extract the letter
                        letterImage = app.LicensePlateROI(:,startIdx(p):endIdx(p));
                        
                        % Show the letter
                        eval(sprintf('app.Char%d = letterImage;',count));
                        
                        % Compare to templates
                        distance = zeros(1,length(app.CandidateImage));
                        for t=1:length(app.CandidateImage)
                            letterImage = imresize(letterImage,size(app.CandidateImage{t,2}));
                            distance(t) = abs(sum((letterImage-double(app.CandidateImage{t,2})).^2,"all"));
                        end
                        [~,idx] = min(distance);
                        letter = app.CandidateImage{idx,1};
                        if strcmp(letter, 'map')
                            letter = '~';
                        end
                        app.LicenseNumberText.Text(count) = letter;
                        count = count + 1;
                    end
                end
            catch
            end
        end
        
    end
    
    methods (Access = public)
        function setBrightnessThreshold(app, threshold)
            app.BrightnessThreshold = threshold;
            app.LicensePlateBW = app.LicensePlateGray < app.BrightnessThreshold;
            findROI(app)
            findChars(app)
            showImage(app)
        end
        
        function setHorizontalThreshold(app, threshold)
            app.HorizontalThreshold = threshold;
            findROI(app)
            findChars(app)
            showImage(app)
        end
        
        function setVerticalThreshold(app, threshold)
            app.VerticalThreshold = threshold;
            findROI(app)
            findChars(app)
            showImage(app)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            showImage(app)
            
            % Load templates into memory
            templateDir = fullfile(pwd,'templates');
            templates = dir(fullfile(templateDir,"*.png"));
            app.CandidateImage = cell(length(templates),2);
            for p=1:length(templates)
                [~,fileName] = fileparts(templates(p).name);
                app.CandidateImage{p,1} = fileName;
                app.CandidateImage{p,2} = imread(fullfile(templates(p).folder,templates(p).name));
            end
            
            % Send unidentifiable unique ID to ThingSpeak to create a usage
            % graph
            if ispc
                [~,mac] = system('getmac');
            elseif ismac
                [~,mac] = system('netstat -I en0');
            else
                [~,mac] = system('/sbin/ifconfig eth0'); 
            end
            try
                thingSpeakWrite(1132380,sum(double(mac(:))),'WriteKey','TFGDNJLRMGFPZXCK')
            catch
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            fileName = uigetfile(["*.jpg","*.png"], "Select license plate picture");
            if fileName ~= 0
                app.SelectlicenseplatepictureEditField.Value = fileName;
                readImage(app)
                showImage(app)
            end
            figure(app.LicensePlateRecognitionUIFigure)
        end

        % Selection changed function: SelectdisplaytypeButtonGroup
        function SelectdisplaytypeButtonGroupSelectionChanged(app, event)
            %            selectedButton = app.SelectdisplaytypeButtonGroup.SelectedObject;
            showImage(app)
        end

        % Button pushed function: UpdateBWThresholdButton
        function UpdateBWThresholdButtonPushed(app, event)
            BlackAndWhiteConverterApp(app.LicensePlateGray, app.BrightnessThreshold, app)
        end

        % Button pushed function: UpdateHorizontalThresholdButton
        function UpdateHorizontalThresholdButtonPushed(app, event)
            ROIFinderApp(app.LicensePlateBW, app.BrightnessThreshold, app)
        end

        % Button pushed function: UpdateVerticalThresholdButton
        function UpdateVerticalThresholdButtonPushed(app, event)
            VerticalThresholdUpdateApp(app.LicensePlateROI, app.VerticalThreshold, app)
        end

        % Menu selected function: 
        % LicenseplaterecognitionMATLABcodeMenu
        function LicenseplaterecognitionMATLABcodeMenuSelected(app, event)
            open('LicensePlateRecognitionFinal.mlx')
        end

        % Menu selected function: ContactthetutorMenu
        function ContactthetutorMenuSelected(app, event)
            web("https://angoratutor.com/contact")
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msg = "License Plate Recognition App, version " + app.Version + newline + newline ...
                + "Explore license plate recognition steps visually" + newline ...
                + "Companion to License Plate Recognition Live Script" + newline ...
                + newline ...
                + "Copyright 2020, Angora Tutor";
            uialert(app.LicensePlateRecognitionUIFigure, ...
                msg, 'License Plate Recognition', 'Icon',"angora_goat_elips.png")            
        end

        % Menu selected function: 
        % LicenseplaterecognitionexplainedMenu
        function LicenseplaterecognitionexplainedMenuSelected(app, event)
            web('https://www.youtube.com/watch?v=AJW9hAw8fVM','-browser')
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            BrowseButtonPushed(app, event);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app.LicensePlateRecognitionUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LicensePlateRecognitionUIFigure = uifigure('Visible', 'off');
            app.LicensePlateRecognitionUIFigure.Name = 'License Plate Recognition';
            app.FileMenu = uimenu(app.LicensePlateRecognitionUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open...';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.LicensePlateRecognitionUIFigure);
            app.HelpMenu.Text = 'Help';
            app.LicenseplaterecognitionexplainedMenu = uimenu(app.HelpMenu);
            app.LicenseplaterecognitionexplainedMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseplaterecognitionexplainedMenuSelected, true);
            app.LicenseplaterecognitionexplainedMenu.Text = 'License plate recognition explained';
            app.LicenseplaterecognitionMATLABcodeMenu = uimenu(app.HelpMenu);
            app.LicenseplaterecognitionMATLABcodeMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseplaterecognitionMATLABcodeMenuSelected, true);
            app.LicenseplaterecognitionMATLABcodeMenu.Text = 'License plate recognition MATLAB code';
            app.ContactthetutorMenu = uimenu(app.HelpMenu);
            app.ContactthetutorMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactthetutorMenuSelected, true);
            app.ContactthetutorMenu.Text = 'Contact the tutor';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.SelectlicenseplatepictureEditFieldLabel = uilabel(app.LicensePlateRecognitionUIFigure);
            app.SelectlicenseplatepictureEditFieldLabel.Text = 'Select license plate picture:';
            app.SelectlicenseplatepictureEditField = uieditfield(app.LicensePlateRecognitionUIFigure, 'text');
            app.BrowseButton = uibutton(app.LicensePlateRecognitionUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FullImageAxes = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.FullImageAxes, 'License Plate Picture')
            xlabel(app.FullImageAxes, '')
            ylabel(app.FullImageAxes, '')
            app.SelectdisplaytypeButtonGroup = uibuttongroup(app.LicensePlateRecognitionUIFigure);
            app.SelectdisplaytypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectdisplaytypeButtonGroupSelectionChanged, true);
            app.SelectdisplaytypeButtonGroup.Title = 'Select display type:';
            app.OriginalButton = uiradiobutton(app.SelectdisplaytypeButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.GrayscaleButton = uiradiobutton(app.SelectdisplaytypeButtonGroup);
            app.GrayscaleButton.Text = 'Gray scale';
            app.BlackWhiteButton = uiradiobutton(app.SelectdisplaytypeButtonGroup);
            app.BlackWhiteButton.Text = 'Black & White';
            app.UpdateBWThresholdButton = uibutton(app.LicensePlateRecognitionUIFigure, 'push');
            app.UpdateBWThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateBWThresholdButtonPushed, true);
            app.UpdateBWThresholdButton.Text = {'Update'; 'BW'; 'Threshold'};
            app.ROIAxes = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.ROIAxes, 'Region of Interest (ROI)')
            xlabel(app.ROIAxes, '')
            ylabel(app.ROIAxes, '')
            app.UpdateHorizontalThresholdButton = uibutton(app.LicensePlateRecognitionUIFigure, 'push');
            app.UpdateHorizontalThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateHorizontalThresholdButtonPushed, true);
            app.UpdateHorizontalThresholdButton.Text = {'Update'; 'Horizontal'; 'Threshold'};
            app.Character1 = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.Character1, '')
            xlabel(app.Character1, '')
            ylabel(app.Character1, '')
            app.Character2 = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.Character2, '')
            xlabel(app.Character2, '')
            ylabel(app.Character2, '')
            app.Character3 = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.Character3, '')
            xlabel(app.Character3, '')
            ylabel(app.Character3, '')
            app.Character4 = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.Character4, '')
            xlabel(app.Character4, '')
            ylabel(app.Character4, '')
            app.Character5 = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.Character5, '')
            xlabel(app.Character5, '')
            ylabel(app.Character5, '')
            app.Character6 = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.Character6, '')
            xlabel(app.Character6, '')
            ylabel(app.Character6, '')
            app.Character7 = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.Character7, '')
            xlabel(app.Character7, '')
            ylabel(app.Character7, '')
            app.Character8 = uiaxes(app.LicensePlateRecognitionUIFigure);
            title(app.Character8, '')
            xlabel(app.Character8, '')
            ylabel(app.Character8, '')
            app.LicenseNumberText = uilabel(app.LicensePlateRecognitionUIFigure);
            app.LicenseNumberText.Text = 'AAA~0000';
            app.LicensePlateNumberLabel = uilabel(app.LicensePlateRecognitionUIFigure);
            app.LicensePlateNumberLabel.Text = 'License Plate Number:';
            app.UpdateVerticalThresholdButton = uibutton(app.LicensePlateRecognitionUIFigure, 'push');
            app.UpdateVerticalThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVerticalThresholdButtonPushed, true);
            app.UpdateVerticalThresholdButton.Text = {'Update'; 'Vertical'; 'Threshold'};
            % Show the figure after all components are created
            app.LicensePlateRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LicensePlateRecognitionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LicensePlateRecognitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LicensePlateRecognitionUIFigure)
        end
    end
end