classdef ROIFinderApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ROIImageAxes     matlab.ui.control.UIAxes
        ThresholdLabel   matlab.ui.control.Label
        ThresholdSlider  matlab.ui.control.Slider
        OKButton         matlab.ui.control.Button
        CancelButton     matlab.ui.control.Button
        BWImageAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LicensePlateBW
        WhiteCountPerRow
        Threshold = 100
        Parent
        ThresholdLine
    end
    
    methods (Access = private)
        function updateROIImage(app)
            regions = app.WhiteCountPerRow > app.Threshold;
            startIdx = find(diff(regions)==1);
            endIdx = find(diff(regions)==-1);
            if length(startIdx) > length(endIdx)
                startIdx = startIdx(1:end-1);
            end
            if length(startIdx) < length(endIdx)
                endIdx = endIdx(1:end-1);
            end
            [~,widestRegionIdx] = max(endIdx-startIdx);
            upperLimitROI = startIdx(widestRegionIdx);
            lowerLimitROI = endIdx(widestRegionIdx);
            licensNumberROI = app.LicensePlateBW(upperLimitROI:lowerLimitROI,:);
            imshow(licensNumberROI, 'Parent', app.ROIImageAxes)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, licensePlateBW, threshold, parentApp)
            app.Threshold = threshold;
            app.ThresholdSlider.Value = app.Threshold;
            app.LicensePlateBW = licensePlateBW;
            imshow(licensePlateBW, 'Parent', app.BWImageAxes)
            
            app.WhiteCountPerRow = sum(licensePlateBW,2);
            app.ThresholdSlider.Limits = [0 max(app.WhiteCountPerRow)];
            
            hold(app.BWImageAxes, 'on')
            plot(app.BWImageAxes, app.WhiteCountPerRow, 1:length(app.WhiteCountPerRow), 'r', 'LineWidth', 5)
            app.ThresholdLine = plot(app.BWImageAxes, [app.Threshold app.Threshold], ylim(app.BWImageAxes), 'g', 'LineWidth', 5);
            app.Parent = parentApp;
            axis(app.BWImageAxes, 'tight')
            hold(app.BWImageAxes, 'off')
            
            updateROIImage(app)
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.ThresholdLine.XData = [value value];
            app.Threshold = value;
            updateROIImage(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setHorizontalThreshold(app.Parent, app.Threshold)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            value = event.Value;
            app.ThresholdLine.XData = [value value];
            app.Threshold = value;
            updateROIImage(app)
            drawnow limitrate
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ROIImageAxes = uiaxes(app.UIFigure);
            title(app.ROIImageAxes, 'ROI')
            xlabel(app.ROIImageAxes, '')
            ylabel(app.ROIImageAxes, '')
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 100;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.BWImageAxes = uiaxes(app.UIFigure);
            title(app.BWImageAxes, 'Black & White Image')
            xlabel(app.BWImageAxes, '')
            ylabel(app.BWImageAxes, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIFinderApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end