classdef VerticalThresholdUpdateApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ThresholdLabel   matlab.ui.control.Label
        ThresholdSlider  matlab.ui.control.Slider
        OKButton         matlab.ui.control.Button
        CancelButton     matlab.ui.control.Button
        ROIImageAxes     matlab.ui.control.UIAxes
        Character1       matlab.ui.control.UIAxes
        Character2       matlab.ui.control.UIAxes
        Character3       matlab.ui.control.UIAxes
        Character4       matlab.ui.control.UIAxes
        Character5       matlab.ui.control.UIAxes
        Character6       matlab.ui.control.UIAxes
        Character7       matlab.ui.control.UIAxes
        Character8       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LicensePlateROI
        WhiteCountPerColumn
        WhiteCountMax
        Threshold = 5
        Parent
        ThresholdLine
    end
    
    methods (Access = private)
        function updateCharImages(app)
            regions = app.WhiteCountPerColumn > app.Threshold;
            startIdx = find(diff(regions)==1);
            endIdx = find(diff(regions)==-1);
            if length(startIdx) > length(endIdx)
                startIdx = startIdx(1:end-1);
            end
            if length(startIdx) < length(endIdx)
                endIdx = endIdx(1:end-1);
            end
            regions = endIdx-startIdx;
            widthThreshold = mean(regions);
            count = 1;
            for p=1:length(regions)
                if (regions(p) > widthThreshold-std(regions)) && (count <= 8)
                    letterImage = app.LicensePlateROI(:,startIdx(p):endIdx(p));
                    parentAxes = sprintf('app.Character%d', count);
                    imshow(letterImage, 'Parent', eval(parentAxes))
                    count = count + 1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, licensePlateROI, threshold, parentApp)
            app.Threshold = threshold;
            app.ThresholdSlider.Value = app.Threshold;
            app.LicensePlateROI = licensePlateROI;
            imshow(licensePlateROI, 'Parent', app.ROIImageAxes)
            
            app.WhiteCountPerColumn = sum(licensePlateROI,1);
            app.WhiteCountMax = max(app.WhiteCountPerColumn);
            app.ThresholdSlider.Limits = [0 max(app.WhiteCountPerColumn)];
            
            hold(app.ROIImageAxes, 'on')
            plot(app.ROIImageAxes, 1:length(app.WhiteCountPerColumn), app.WhiteCountMax-app.WhiteCountPerColumn, 'r', 'LineWidth', 3)
            app.ThresholdLine = plot(app.ROIImageAxes, ...
                xlim(app.ROIImageAxes), ...
                app.WhiteCountMax - [app.Threshold app.Threshold], 'g', 'LineWidth', 3);
            app.Parent = parentApp;
            axis(app.ROIImageAxes, 'tight')
            hold(app.ROIImageAxes, 'off')
            
            updateCharImages(app)
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.ThresholdLine.YData = app.WhiteCountMax - [value value];
            app.Threshold = value;
            updateCharImages(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            setVerticalThreshold(app.Parent, app.Threshold)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            value = event.Value;
            app.ThresholdLine.YData = app.WhiteCountMax - [value value];
            app.Threshold = value;
            updateCharImages(app)
            drawnow limitrate
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 100;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ROIImageAxes = uiaxes(app.UIFigure);
            title(app.ROIImageAxes, 'ROI Image')
            xlabel(app.ROIImageAxes, '')
            ylabel(app.ROIImageAxes, '')
            app.Character1 = uiaxes(app.UIFigure);
            title(app.Character1, '')
            xlabel(app.Character1, '')
            ylabel(app.Character1, '')
            app.Character2 = uiaxes(app.UIFigure);
            title(app.Character2, '')
            xlabel(app.Character2, '')
            ylabel(app.Character2, '')
            app.Character3 = uiaxes(app.UIFigure);
            title(app.Character3, '')
            xlabel(app.Character3, '')
            ylabel(app.Character3, '')
            app.Character4 = uiaxes(app.UIFigure);
            title(app.Character4, '')
            xlabel(app.Character4, '')
            ylabel(app.Character4, '')
            app.Character5 = uiaxes(app.UIFigure);
            title(app.Character5, '')
            xlabel(app.Character5, '')
            ylabel(app.Character5, '')
            app.Character6 = uiaxes(app.UIFigure);
            title(app.Character6, '')
            xlabel(app.Character6, '')
            ylabel(app.Character6, '')
            app.Character7 = uiaxes(app.UIFigure);
            title(app.Character7, '')
            xlabel(app.Character7, '')
            ylabel(app.Character7, '')
            app.Character8 = uiaxes(app.UIFigure);
            title(app.Character8, '')
            xlabel(app.Character8, '')
            ylabel(app.Character8, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VerticalThresholdUpdateApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end